classdef predator_prey_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ODESolverDropDownLabel          matlab.ui.control.Label
        ODESolverDropDown               matlab.ui.control.DropDown
        PreyLabel                       matlab.ui.control.Label
        GrowthRateEditFieldLabel        matlab.ui.control.Label
        GrowthRateEditField             matlab.ui.control.EditField
        EffectofPredatorEditFieldLabel  matlab.ui.control.Label
        EffectofPredatorEditField       matlab.ui.control.EditField
        PredatorLabel                   matlab.ui.control.Label
        GrowthRateEditField_2Label      matlab.ui.control.Label
        GrowthRateEditField_2           matlab.ui.control.EditField
        EffectofPreyEditFieldLabel      matlab.ui.control.Label
        EffectofPreyEditField           matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.EditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.EditField
        StartingNumberEditFieldLabel    matlab.ui.control.Label
        StartingNumberEditField         matlab.ui.control.EditField
        StartingNumberEditField_2Label  matlab.ui.control.Label
        StartingNumberEditField_2       matlab.ui.control.EditField
        MaxHEditFieldLabel              matlab.ui.control.Label
        MaxHEditField                   matlab.ui.control.EditField
        MinHEditFieldLabel              matlab.ui.control.Label
        MinHEditField                   matlab.ui.control.EditField
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.EditField
        PlotButton                      matlab.ui.control.Button
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ODESolverDropDown
        function ODESolverDropDownValueChanged(app, event)
            value = app.ODESolverDropDown.Value;
            if strcmp(value,'EULER') || strcmp(value,'MEULER') || strcmp(value,'RK4')
                app.MaxHEditField.Visible = 'off';
                app.MaxHEditFieldLabel.Visible = 'off';
                app.MinHEditField.Visible = 'off';
                app.MinHEditFieldLabel.Visible = 'off';
                app.ToleranceEditField.Visible = 'off';
                app.ToleranceEditFieldLabel.Visible ='off';
                app.NEditField.Visible = 'on'; 
                app.NEditFieldLabel.Visible = 'on';
            elseif strcmp(value,'RK45')
                app.MaxHEditField.Visible = 'on';
                app.MaxHEditFieldLabel.Visible = 'on';
                app.MinHEditField.Visible = 'on';
                app.MinHEditFieldLabel.Visible = 'on';
                app.ToleranceEditField.Visible = 'on';
                app.ToleranceEditFieldLabel.Visible = 'on';
                app.NEditField.Visible = 'off';
                app.NEditFieldLabel.Visible = 'off';
            else
                app.MaxHEditField.Visible = 'off';
                app.MaxHEditFieldLabel.Visible = 'off';
                app.MinHEditField.Visible = 'off';
                app.MinHEditFieldLabel.Visible = 'off';
                app.ToleranceEditField.Visible = 'off';
                app.ToleranceEditFieldLabel.Visible = 'off';
                app.NEditField.Visible = 'off'; 
                app.NEditFieldLabel.Visible = 'off';
            end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            prey_growth = str2double(app.GrowthRateEditField.Value);
            pred_e = str2double(app.EffectofPredatorEditField.Value);
            predator_growth = str2double(app.GrowthRateEditField_2.Value);
            prey_e = str2double(app.EffectofPreyEditField.Value);
            f = @(t,y) [(prey_growth+pred_e*y(2))*y(1);(predator_growth + prey_e * y(1))*y(2)];
            disp(f);
            tspan = [str2double(app.StartTimeEditField.Value),str2double(app.EndTimeEditField.Value)];
            y0 = [str2double(app.StartingNumberEditField.Value),str2double(app.StartingNumberEditField_2.Value)];
            ode = app.ODESolverDropDown.Value;
            n = str2double(app.NEditField.Value);
            maxh = str2double(app.MaxHEditField.Value);
            minh = str2double(app.MinHEditField.Value);
            tol = str2double(app.ToleranceEditField.Value);
            if strcmp(ode,'EULER')
                [ts,ys] = euler(f,tspan,y0,n);
                plot(app.UIAxes, ts,ys);
                plot(app.UIAxes2,ys(:,1),ys(:,2));
                legend(app.UIAxes,'Prey','Predators','Location','North')
                app.UIAxes.Title.String='Eulers Method';
            end 
            if strcmp(ode,'MEULER')
                [ts,ys] = modified_euler(f,tspan,y0,n);
                plot(app.UIAxes, ts,ys);
                plot(app.UIAxes2,ys(:,1),ys(:,2));
                legend(app.UIAxes,'Prey','Predators','Location','North')
                app.UIAxes.Title.String='Modified Eulers Method';
            end
            if strcmp(ode,'RK4')
                [ts,ys] = rk4(f,tspan,y0,n);
                plot(app.UIAxes, ts,ys);
                plot(app.UIAxes2,ys(:,1),ys(:,2));
                legend(app.UIAxes,'Prey','Predators','Location','North')

                app.UIAxes.Title.String='RK4';
            end
            if strcmp(ode,'RK45')
                [ts,ys] = rk45(f, tspan, y0, tol, maxh, minh);
                plot(app.UIAxes, ts,ys);
                plot(app.UIAxes2,ys(:,1),ys(:,2));
                legend(app.UIAxes,'Prey','Predators','Location','North')

                app.UIAxes.Title.String='RK45';
            end
            if strcmp(ode,'ODE45')
                [ts,ys] = ode45(f, tspan, y0);
                plot(app.UIAxes, ts,ys);
                plot(app.UIAxes2,ys(:,1),ys(:,2));
                legend(app.UIAxes,'Prey','Predators','Location','North')

                app.UIAxes.Title.String='ODE45';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ODESolverDropDownLabel = uilabel(app.LeftPanel);
            app.ODESolverDropDownLabel.Text = 'ODE Solver';
            app.ODESolverDropDown = uidropdown(app.LeftPanel);
            app.ODESolverDropDown.Items = {'EULER', 'MEULER', 'RK4', 'RK45', 'ODE45'};
            app.ODESolverDropDown.ValueChangedFcn = createCallbackFcn(app, @ODESolverDropDownValueChanged, true);
            app.ODESolverDropDown.Value = 'EULER';
            app.PreyLabel = uilabel(app.LeftPanel);
            app.PreyLabel.Text = 'Prey';
            app.GrowthRateEditFieldLabel = uilabel(app.LeftPanel);
            app.GrowthRateEditFieldLabel.Text = 'Growth Rate';
            app.GrowthRateEditField = uieditfield(app.LeftPanel, 'text');
            app.GrowthRateEditField.Value = '2';
            app.EffectofPredatorEditFieldLabel = uilabel(app.LeftPanel);
            app.EffectofPredatorEditFieldLabel.Text = 'Effect of Predator';
            app.EffectofPredatorEditField = uieditfield(app.LeftPanel, 'text');
            app.EffectofPredatorEditField.Value = '-.5';
            app.PredatorLabel = uilabel(app.LeftPanel);
            app.PredatorLabel.Text = 'Predator';
            app.GrowthRateEditField_2Label = uilabel(app.LeftPanel);
            app.GrowthRateEditField_2Label.Text = 'Growth Rate';
            app.GrowthRateEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.GrowthRateEditField_2.Value = '-1';
            app.EffectofPreyEditFieldLabel = uilabel(app.LeftPanel);
            app.EffectofPreyEditFieldLabel.Text = 'Effect of Prey';
            app.EffectofPreyEditField = uieditfield(app.LeftPanel, 'text');
            app.EffectofPreyEditField.Value = '.5';
            app.StartTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.LeftPanel, 'text');
            app.EndTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.LeftPanel, 'text');
            app.StartingNumberEditFieldLabel = uilabel(app.LeftPanel);
            app.StartingNumberEditFieldLabel.Text = 'Starting Number';
            app.StartingNumberEditField = uieditfield(app.LeftPanel, 'text');
            app.StartingNumberEditField.Value = '6';
            app.StartingNumberEditField_2Label = uilabel(app.LeftPanel);
            app.StartingNumberEditField_2Label.Text = 'Starting Number';
            app.StartingNumberEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.StartingNumberEditField_2.Value = '2';
            app.MaxHEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxHEditFieldLabel.Visible = 'off';
            app.MaxHEditFieldLabel.Text = 'Max H';
            app.MaxHEditField = uieditfield(app.LeftPanel, 'text');
            app.MaxHEditField.Visible = 'off';
            app.MinHEditFieldLabel = uilabel(app.LeftPanel);
            app.MinHEditFieldLabel.Visible = 'off';
            app.MinHEditFieldLabel.Text = 'Min H';
            app.MinHEditField = uieditfield(app.LeftPanel, 'text');
            app.MinHEditField.Visible = 'off';
            app.NEditFieldLabel = uilabel(app.LeftPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LeftPanel, 'text');
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ToleranceEditFieldLabel = uilabel(app.LeftPanel);
            app.ToleranceEditFieldLabel.Visible = 'off';
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.LeftPanel, 'text');
            app.ToleranceEditField.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Predator Prey Population')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Population')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Phase Plot')
            xlabel(app.UIAxes2, 'Prey')
            ylabel(app.UIAxes2, 'Predator')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = predator_prey_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end